classdef jurp_app_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MotionControlTab               matlab.ui.container.Tab
        MotionControllerLabel          matlab.ui.control.Label
        HomeButton                     matlab.ui.control.Button
        SimultaneouscommandsPanel      matlab.ui.container.Panel
        ShoulderEditField              matlab.ui.control.NumericEditField
        ShoulderEditFieldLabel         matlab.ui.control.Label
        TransverseEditField            matlab.ui.control.NumericEditField
        TransverseEditFieldLabel       matlab.ui.control.Label
        ElbowEditField                 matlab.ui.control.NumericEditField
        ElbowEditFieldLabel            matlab.ui.control.Label
        PublishButton                  matlab.ui.control.StateButton
        IndividualcommandsPanel        matlab.ui.container.Panel
        ShoulderSpinner                matlab.ui.control.Spinner
        TransverseSpinner              matlab.ui.control.Spinner
        ElbowSpinner                   matlab.ui.control.Spinner
        ShoulderradSliderLabel         matlab.ui.control.Label
        ShoulderradSlider              matlab.ui.control.Slider
        TransverseradSliderLabel       matlab.ui.control.Label
        TransverseradSlider            matlab.ui.control.Slider
        ElbowradSliderLabel            matlab.ui.control.Label
        ElbowradSlider                 matlab.ui.control.Slider
        StartButton                    matlab.ui.control.Button
        AbsoluteControlTab             matlab.ui.container.Tab
        AbsoluteControllerDANGERLabel  matlab.ui.control.Label
        SimultaneouscommandsPanel_2    matlab.ui.container.Panel
        ShoulderEditField_5            matlab.ui.control.NumericEditField
        ShoulderEditField_5Label       matlab.ui.control.Label
        TransverseEditField_5          matlab.ui.control.NumericEditField
        TransverseEditField_5Label     matlab.ui.control.Label
        ElbowEditField_5               matlab.ui.control.NumericEditField
        ElbowEditField_5Label          matlab.ui.control.Label
        PublishButton_2                matlab.ui.control.StateButton
        DONTUSETHISWITHOUTSUPERVISIONOFJONLabel  matlab.ui.control.Label
        JuggleTab                      matlab.ui.container.Tab
        StartPanel                     matlab.ui.container.Panel
        ShoulderEditField_2            matlab.ui.control.NumericEditField
        ShoulderEditField_2Label       matlab.ui.control.Label
        TransverseEditField_2          matlab.ui.control.NumericEditField
        TransverseEditField_2Label     matlab.ui.control.Label
        ElbowEditField_2               matlab.ui.control.NumericEditField
        ElbowEditField_2Label          matlab.ui.control.Label
        TossPanel                      matlab.ui.container.Panel
        ShoulderEditField_3            matlab.ui.control.NumericEditField
        ShoulderEditField_3Label       matlab.ui.control.Label
        TransverseEditField_3          matlab.ui.control.NumericEditField
        TransverseEditField_3Label     matlab.ui.control.Label
        ElbowEditField_3               matlab.ui.control.NumericEditField
        ElbowEditField_3Label          matlab.ui.control.Label
        CatchPanel                     matlab.ui.container.Panel
        ShoulderEditField_4            matlab.ui.control.NumericEditField
        ShoulderEditField_4Label       matlab.ui.control.Label
        TransverseEditField_4          matlab.ui.control.NumericEditField
        TransverseEditField_4Label     matlab.ui.control.Label
        ElbowEditField_4               matlab.ui.control.NumericEditField
        ElbowEditField_4Label          matlab.ui.control.Label
        JuggleButton                   matlab.ui.control.Button
        GotostartButton                matlab.ui.control.Button
        IKTab                          matlab.ui.container.Tab
        TestIKPanel                    matlab.ui.container.Panel
        ShoulderEditField_6Label       matlab.ui.control.Label
        ShoulderEditField_6            matlab.ui.control.NumericEditField
        TransverseEditField_6Label     matlab.ui.control.Label
        TransverseEditField_6          matlab.ui.control.NumericEditField
        ElbowEditField_6Label          matlab.ui.control.Label
        ElbowEditField_6               matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        PublishButton_3                matlab.ui.control.Button
        CalculatePublishButton         matlab.ui.control.Button
        CatchPanel_2                   matlab.ui.container.Panel
        SamplingthresholdEditFieldLabel  matlab.ui.control.Label
        SamplingthresholdEditField     matlab.ui.control.NumericEditField
        CatchingheightEditFieldLabel   matlab.ui.control.Label
        CatchingheightEditField        matlab.ui.control.NumericEditField
        CatchButton                    matlab.ui.control.Button
        HomeButton_2                   matlab.ui.control.Button
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           

        end

        % Value changed function: ShoulderradSlider
        function ShoulderradSliderValueChanged(app, event)
            value = app.ShoulderradSlider.Value;
            app.ShoulderSpinner.Value = value;
            app.ShoulderEditField.Value = value;
            
            
        end

        % Value changed function: TransverseradSlider
        function TransverseradSliderValueChanged(app, event)
            value = app.TransverseradSlider.Value;
            app.TransverseSpinner.Value = value;
            app.TransverseEditField.Value = value;
            
            
        end

        % Value changed function: ElbowradSlider
        function ElbowradSliderValueChanged(app, event)
            value = app.ElbowradSlider.Value;
            app.ElbowSpinner.Value = value;
            app.ElbowradSlider.Value = value;
            app.ElbowEditField.Value = value;
            
            
        end

        % Value changed function: ShoulderSpinner
        function ShoulderSpinnerValueChanged(app, event)
            value = app.ShoulderSpinner.Value;
            app.ShoulderradSlider.Value = value;
            app.ShoulderEditField.Value = value;
            
            
        end

        % Value changed function: TransverseSpinner
        function TransverseSpinnerValueChanged(app, event)
            value = app.TransverseSpinner.Value;
            app.TransverseradSlider.Value=value;
            app.TransverseEditField.Value = value;
            
            
        end

        % Value changed function: ElbowSpinner
        function ElbowSpinnerValueChanged(app, event)
            value = app.ElbowSpinner.Value;
            app.ElbowradSlider.Value=value;
            app.ElbowEditField.Value = value;
           
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.ShoulderradSlider.Value = 0;
            app.ShoulderSpinner.Value = 0;
            app.ShoulderEditField.Value = 0;
            app.TransverseSpinner.Value = 0;
            app.TransverseradSlider.Value = 0;
            app.TransverseEditField.Value = 0;
            app.ElbowSpinner.Value = pi/2;
            app.ElbowradSlider.Value = pi/2;
            app.ElbowEditField.Value = pi/2;
            
            publish_motion(0, 0, pi/2);
                        
        end

        % Value changed function: PublishButton
        function PublishButtonValueChanged(app, event)
            value1 = app.ShoulderEditField.Value;
            value2 = app.TransverseEditField.Value;
            value3 = app.ElbowEditField.Value;
            
            app.ShoulderradSlider.Value = value1;
            app.ShoulderSpinner.Value = value1;
            app.TransverseSpinner.Value = value2;
            app.TransverseradSlider.Value = value2;
            app.ElbowSpinner.Value = value3;
            app.ElbowradSlider.Value = value3;            
            

            publish_motion(value1, value2, value3);           
        end

        % Value changed function: ShoulderEditField
        function ShoulderEditFieldValueChanged(app, event)
            value = app.ShoulderEditField.Value;
            
        end

        % Value changed function: TransverseEditField
        function TransverseEditFieldValueChanged(app, event)
            value = app.TransverseEditField.Value;
            
        end

        % Value changed function: ElbowEditField
        function ElbowEditFieldValueChanged(app, event)
            value = app.ElbowEditField.Value;
            
        end

        % Callback function
        function AnalyzeTabSizeChanged(app, event)

            
        end

        % Button pushed function: JuggleButton
        function JuggleButtonPushed(app, event)
            toss_value1 = app.ShoulderEditField_3.Value;
            toss_value2 = app.TransverseEditField_3.Value;
            toss_value3 = app.ElbowEditField_3.Value;
            
            catch_value1 = app.ShoulderEditField_4.Value;
            catch_value2 = app.TransverseEditField_4.Value;
            catch_value3 = app.ElbowEditField_4.Value;
            
            app.ShoulderradSlider.Value = catch_value1;
            app.ShoulderSpinner.Value = catch_value1;
            app.TransverseSpinner.Value = catch_value2;
            app.TransverseradSlider.Value = catch_value2;
            app.ElbowSpinner.Value = catch_value3;
            app.ElbowradSlider.Value = catch_value3;            
            
            publish_trajectory([toss_value1 catch_value1], [toss_value2 catch_value2], [toss_value3 catch_value3]);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.ShoulderradSlider.Value = 0;
            app.ShoulderSpinner.Value = 0;
            app.ShoulderEditField.Value = 0;
            app.TransverseSpinner.Value = 0;
            app.TransverseradSlider.Value = 0;
            app.TransverseEditField.Value = 0;
            app.ElbowSpinner.Value = 0;
            app.ElbowradSlider.Value = 0;
            app.ElbowEditField.Value = 0;
            
            publish_motion(0, 0, 0);
        end

        % Callback function
        function ZeroDANGERButtonPushed(app, event)
            
        end

        % Value changed function: PublishButton_2
        function PublishButton_2ValueChanged(app, event)
            value = app.PublishButton_2.Value;
            value1 = app.ShoulderEditField_5.Value;
            value2 = app.TransverseEditField_5.Value;
            value3 = app.ElbowEditField_5.Value;
            
            app.ShoulderradSlider.Value = value1;
            app.ShoulderSpinner.Value = value1;
            app.ShoulderEditField.Value = value1;
            app.TransverseSpinner.Value = value2;
            app.TransverseradSlider.Value = value2;
            app.TransverseEditField.Value = value2;
            app.ElbowSpinner.Value = value3;
            app.ElbowradSlider.Value = value3;
            app.ElbowEditField.Value = value3;           
            

            RefPub = rospublisher('reference','geometry_msgs/Vector3');
            RefMsg = rosmessage('geometry_msgs/Vector3');
            RefMsg.X = value1;
            RefMsg.Y = value2;
            RefMsg.Z = value3;
            send(RefPub,RefMsg);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            RefPub3 = rospublisher('reference3','std_msgs/Float64');
            RefMsg3 = rosmessage('std_msgs/Float64');
            RefMsg3.Data = value3;
            send(RefPub3,RefMsg3);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: GotostartButton
        function GotostartButtonPushed(app, event)
            start_value1 = app.ShoulderEditField_2.Value;
            start_value2 = app.TransverseEditField_2.Value;
            start_value3 = app.ElbowEditField_2.Value;
            
            app.ShoulderradSlider.Value = start_value1;
            app.ShoulderSpinner.Value = start_value1;
            app.TransverseSpinner.Value = start_value2;
            app.TransverseradSlider.Value = start_value2;
            app.ElbowSpinner.Value = start_value3;
            app.ElbowradSlider.Value = start_value3;            
            
            publish_motion(start_value1, start_value2, start_value3);
        end

        % Callback function
        function TimingbetweentossandcatchEditFieldValueChanged(app, event)
            value = app.TimingbetweentossandcatchEditField.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            pos = track_ball();
            jointstates = ik_fast(pos);
            
            app.ShoulderEditField_6.Value = jointstates(1);
            app.TransverseEditField_6.Value= jointstates(2);
            app.ElbowEditField_6.Value= jointstates(3);
        end

        % Value changed function: ShoulderEditField_6
        function ShoulderEditField_6ValueChanged(app, event)
            value = app.ShoulderEditField_6.Value;
            
        end

        % Button pushed function: PublishButton_3
        function PublishButton_3Pushed(app, event)
            value1 = app.ShoulderEditField_6.Value;
            value2 = app.TransverseEditField_6.Value;
            value3 = app.ElbowEditField_6.Value;
            publish_motion(value1, value2, value3);
            
        end

        % Button pushed function: CalculatePublishButton
        function CalculatePublishButtonPushed(app, event)
            pos = track_ball();
            JointStates = ik_fast(pos);
            publish_motion(JointStates(1), JointStates(2), JointStates(3));
            
            app.ShoulderEditField_6.Value = JointStates(1);
            app.TransverseEditField_6.Value = JointStates(2);
            app.ElbowEditField_6.Value = JointStates(3);
            
            
        end

        % Button pushed function: CatchButton
        function CatchButtonPushed(app, event)
            sample_th = app.SamplingthresholdEditField.Value;
            catch_height = app.CatchingheightEditField.Value;
            catch_ball(sample_th, catch_height)
        end

        % Button pushed function: HomeButton_2
        function HomeButton_2Pushed(app, event)
            publish_motion(0,0,pi/2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotionControlTab = uitab(app.TabGroup);
            app.MotionControlTab.Title = 'Motion Control';
            app.MotionControllerLabel = uilabel(app.MotionControlTab);
            app.MotionControllerLabel.Text = 'Motion Controller';
            app.HomeButton = uibutton(app.MotionControlTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.SimultaneouscommandsPanel = uipanel(app.MotionControlTab);
            app.SimultaneouscommandsPanel.Title = 'Simultaneous commands';
            app.ShoulderEditField = uieditfield(app.SimultaneouscommandsPanel, 'numeric');
            app.ShoulderEditField.Limits = [-1.5708 1.5708];
            app.ShoulderEditField.ValueChangedFcn = createCallbackFcn(app, @ShoulderEditFieldValueChanged, true);
            app.ShoulderEditFieldLabel = uilabel(app.SimultaneouscommandsPanel);
            app.ShoulderEditFieldLabel.Text = 'Shoulder';
            app.TransverseEditField = uieditfield(app.SimultaneouscommandsPanel, 'numeric');
            app.TransverseEditField.Limits = [-0.8 0.8];
            app.TransverseEditField.ValueChangedFcn = createCallbackFcn(app, @TransverseEditFieldValueChanged, true);
            app.TransverseEditFieldLabel = uilabel(app.SimultaneouscommandsPanel);
            app.TransverseEditFieldLabel.Text = 'Transverse';
            app.ElbowEditField = uieditfield(app.SimultaneouscommandsPanel, 'numeric');
            app.ElbowEditField.Limits = [0 2.35];
            app.ElbowEditField.ValueChangedFcn = createCallbackFcn(app, @ElbowEditFieldValueChanged, true);
            app.ElbowEditFieldLabel = uilabel(app.SimultaneouscommandsPanel);
            app.ElbowEditFieldLabel.Text = 'Elbow';
            app.PublishButton = uibutton(app.SimultaneouscommandsPanel, 'state');
            app.PublishButton.ValueChangedFcn = createCallbackFcn(app, @PublishButtonValueChanged, true);
            app.PublishButton.Text = 'Publish';
            app.IndividualcommandsPanel = uipanel(app.MotionControlTab);
            app.IndividualcommandsPanel.Title = 'Individual commands';
            app.ShoulderSpinner = uispinner(app.IndividualcommandsPanel);
            app.ShoulderSpinner.Step = 0.1;
            app.ShoulderSpinner.Limits = [-1.5708 1.5708];
            app.ShoulderSpinner.ValueChangedFcn = createCallbackFcn(app, @ShoulderSpinnerValueChanged, true);
            app.TransverseSpinner = uispinner(app.IndividualcommandsPanel);
            app.TransverseSpinner.Step = 0.1;
            app.TransverseSpinner.Limits = [-3.1416 3.1416];
            app.TransverseSpinner.ValueChangedFcn = createCallbackFcn(app, @TransverseSpinnerValueChanged, true);
            app.ElbowSpinner = uispinner(app.IndividualcommandsPanel);
            app.ElbowSpinner.Step = 0.1;
            app.ElbowSpinner.Limits = [0 2.35];
            app.ElbowSpinner.ValueChangedFcn = createCallbackFcn(app, @ElbowSpinnerValueChanged, true);
            app.ShoulderradSliderLabel = uilabel(app.IndividualcommandsPanel);
            app.ShoulderradSliderLabel.Text = 'Shoulder (rad)';
            app.ShoulderradSlider = uislider(app.IndividualcommandsPanel);
            app.ShoulderradSlider.Limits = [-1.5707963267949 1.5707963267949];
            app.ShoulderradSlider.MajorTicks = [-1.5707963267949 1.5707963267949];
            app.ShoulderradSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderradSliderValueChanged, true);
            app.ShoulderradSlider.MinorTicks = 0;
            app.TransverseradSliderLabel = uilabel(app.IndividualcommandsPanel);
            app.TransverseradSliderLabel.Text = 'Transverse (rad)';
            app.TransverseradSlider = uislider(app.IndividualcommandsPanel);
            app.TransverseradSlider.Limits = [-0.8 0.8];
            app.TransverseradSlider.MajorTicks = [-0.8 0.8];
            app.TransverseradSlider.ValueChangedFcn = createCallbackFcn(app, @TransverseradSliderValueChanged, true);
            app.TransverseradSlider.MinorTicks = 0;
            app.ElbowradSliderLabel = uilabel(app.IndividualcommandsPanel);
            app.ElbowradSliderLabel.Text = 'Elbow (rad)';
            app.ElbowradSlider = uislider(app.IndividualcommandsPanel);
            app.ElbowradSlider.Limits = [0 2.35];
            app.ElbowradSlider.MajorTicks = [0 1.5707963267949 2.35];
            app.ElbowradSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowradSliderValueChanged, true);
            app.ElbowradSlider.MinorTicks = 0;
            app.StartButton = uibutton(app.MotionControlTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AbsoluteControlTab = uitab(app.TabGroup);
            app.AbsoluteControlTab.Title = 'Absolute Control';
            app.AbsoluteControllerDANGERLabel = uilabel(app.AbsoluteControlTab);
            app.AbsoluteControllerDANGERLabel.Text = 'Absolute Controller: DANGER';
            app.SimultaneouscommandsPanel_2 = uipanel(app.AbsoluteControlTab);
            app.SimultaneouscommandsPanel_2.Title = 'Simultaneous commands';
            app.ShoulderEditField_5 = uieditfield(app.SimultaneouscommandsPanel_2, 'numeric');
            app.ShoulderEditField_5.Limits = [-1.5708 1.5708];
            app.ShoulderEditField_5Label = uilabel(app.SimultaneouscommandsPanel_2);
            app.ShoulderEditField_5Label.Text = 'Shoulder';
            app.TransverseEditField_5 = uieditfield(app.SimultaneouscommandsPanel_2, 'numeric');
            app.TransverseEditField_5.Limits = [-0.8 0.8];
            app.TransverseEditField_5Label = uilabel(app.SimultaneouscommandsPanel_2);
            app.TransverseEditField_5Label.Text = 'Transverse';
            app.ElbowEditField_5 = uieditfield(app.SimultaneouscommandsPanel_2, 'numeric');
            app.ElbowEditField_5.Limits = [0 2.35];
            app.ElbowEditField_5Label = uilabel(app.SimultaneouscommandsPanel_2);
            app.ElbowEditField_5Label.Text = 'Elbow';
            app.PublishButton_2 = uibutton(app.SimultaneouscommandsPanel_2, 'state');
            app.PublishButton_2.ValueChangedFcn = createCallbackFcn(app, @PublishButton_2ValueChanged, true);
            app.PublishButton_2.Text = 'Publish';
            app.DONTUSETHISWITHOUTSUPERVISIONOFJONLabel = uilabel(app.AbsoluteControlTab);
            app.DONTUSETHISWITHOUTSUPERVISIONOFJONLabel.Text = 'DON''T USE THIS WITHOUT SUPERVISION OF JON!!';
            app.JuggleTab = uitab(app.TabGroup);
            app.JuggleTab.Title = 'Juggle';
            app.StartPanel = uipanel(app.JuggleTab);
            app.StartPanel.Title = 'Start';
            app.ShoulderEditField_2 = uieditfield(app.StartPanel, 'numeric');
            app.ShoulderEditField_2.Limits = [-1.5708 1.5708];
            app.ShoulderEditField_2Label = uilabel(app.StartPanel);
            app.ShoulderEditField_2Label.Text = 'Shoulder';
            app.TransverseEditField_2 = uieditfield(app.StartPanel, 'numeric');
            app.TransverseEditField_2.Limits = [-0.8 0.8];
            app.TransverseEditField_2Label = uilabel(app.StartPanel);
            app.TransverseEditField_2Label.Text = 'Transverse';
            app.ElbowEditField_2 = uieditfield(app.StartPanel, 'numeric');
            app.ElbowEditField_2.Limits = [0 4.7124];
            app.ElbowEditField_2.Value = 0.5;
            app.ElbowEditField_2Label = uilabel(app.StartPanel);
            app.ElbowEditField_2Label.Text = 'Elbow';
            app.TossPanel = uipanel(app.JuggleTab);
            app.TossPanel.Title = 'Toss';
            app.ShoulderEditField_3 = uieditfield(app.TossPanel, 'numeric');
            app.ShoulderEditField_3.Limits = [-1.5708 1.5708];
            app.ShoulderEditField_3.Value = 0.2;
            app.ShoulderEditField_3Label = uilabel(app.TossPanel);
            app.ShoulderEditField_3Label.Text = 'Shoulder';
            app.TransverseEditField_3 = uieditfield(app.TossPanel, 'numeric');
            app.TransverseEditField_3.Limits = [-0.8 0.8];
            app.TransverseEditField_3Label = uilabel(app.TossPanel);
            app.TransverseEditField_3Label.Text = 'Transverse';
            app.ElbowEditField_3 = uieditfield(app.TossPanel, 'numeric');
            app.ElbowEditField_3.Limits = [0 4.7124];
            app.ElbowEditField_3.Value = 2;
            app.ElbowEditField_3Label = uilabel(app.TossPanel);
            app.ElbowEditField_3Label.Text = 'Elbow';
            app.CatchPanel = uipanel(app.JuggleTab);
            app.CatchPanel.Title = 'Catch';
            app.ShoulderEditField_4 = uieditfield(app.CatchPanel, 'numeric');
            app.ShoulderEditField_4.Limits = [-1.5708 1.5708];
            app.ShoulderEditField_4.Value = 0.2;
            app.ShoulderEditField_4Label = uilabel(app.CatchPanel);
            app.ShoulderEditField_4Label.Text = 'Shoulder';
            app.TransverseEditField_4 = uieditfield(app.CatchPanel, 'numeric');
            app.TransverseEditField_4.Limits = [-0.8 0.8];
            app.TransverseEditField_4.Value = -0.1;
            app.TransverseEditField_4Label = uilabel(app.CatchPanel);
            app.TransverseEditField_4Label.Text = 'Transverse';
            app.ElbowEditField_4 = uieditfield(app.CatchPanel, 'numeric');
            app.ElbowEditField_4.Limits = [0 4.7124];
            app.ElbowEditField_4.Value = 2;
            app.ElbowEditField_4Label = uilabel(app.CatchPanel);
            app.ElbowEditField_4Label.Text = 'Elbow';
            app.JuggleButton = uibutton(app.JuggleTab, 'push');
            app.JuggleButton.ButtonPushedFcn = createCallbackFcn(app, @JuggleButtonPushed, true);
            app.JuggleButton.Text = 'Juggle';
            app.GotostartButton = uibutton(app.JuggleTab, 'push');
            app.GotostartButton.ButtonPushedFcn = createCallbackFcn(app, @GotostartButtonPushed, true);
            app.GotostartButton.Text = 'Go to start';
            app.IKTab = uitab(app.TabGroup);
            app.IKTab.Title = 'IK';
            app.TestIKPanel = uipanel(app.IKTab);
            app.TestIKPanel.Title = 'Test IK';
            app.ShoulderEditField_6Label = uilabel(app.TestIKPanel);
            app.ShoulderEditField_6Label.Text = 'Shoulder';
            app.ShoulderEditField_6 = uieditfield(app.TestIKPanel, 'numeric');
            app.ShoulderEditField_6.ValueChangedFcn = createCallbackFcn(app, @ShoulderEditField_6ValueChanged, true);
            app.ShoulderEditField_6.Editable = 'off';
            app.TransverseEditField_6Label = uilabel(app.TestIKPanel);
            app.TransverseEditField_6Label.Text = 'Transverse';
            app.TransverseEditField_6 = uieditfield(app.TestIKPanel, 'numeric');
            app.TransverseEditField_6.Editable = 'off';
            app.ElbowEditField_6Label = uilabel(app.TestIKPanel);
            app.ElbowEditField_6Label.Text = 'Elbow';
            app.ElbowEditField_6 = uieditfield(app.TestIKPanel, 'numeric');
            app.ElbowEditField_6.Editable = 'off';
            app.CalculateButton = uibutton(app.TestIKPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PublishButton_3 = uibutton(app.TestIKPanel, 'push');
            app.PublishButton_3.ButtonPushedFcn = createCallbackFcn(app, @PublishButton_3Pushed, true);
            app.PublishButton_3.Text = 'Publish';
            app.CalculatePublishButton = uibutton(app.TestIKPanel, 'push');
            app.CalculatePublishButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePublishButtonPushed, true);
            app.CalculatePublishButton.Text = 'Calculate & Publish';
            app.CatchPanel_2 = uipanel(app.IKTab);
            app.CatchPanel_2.Title = 'Catch';
            app.SamplingthresholdEditFieldLabel = uilabel(app.CatchPanel_2);
            app.SamplingthresholdEditFieldLabel.Text = 'Sampling threshold';
            app.SamplingthresholdEditField = uieditfield(app.CatchPanel_2, 'numeric');
            app.SamplingthresholdEditField.Value = 1;
            app.CatchingheightEditFieldLabel = uilabel(app.CatchPanel_2);
            app.CatchingheightEditFieldLabel.Text = 'Catching height';
            app.CatchingheightEditField = uieditfield(app.CatchPanel_2, 'numeric');
            app.CatchingheightEditField.Value = 1;
            app.CatchButton = uibutton(app.CatchPanel_2, 'push');
            app.CatchButton.ButtonPushedFcn = createCallbackFcn(app, @CatchButtonPushed, true);
            app.CatchButton.Text = 'Catch';
            app.HomeButton_2 = uibutton(app.IKTab, 'push');
            app.HomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_2Pushed, true);
            app.HomeButton_2.Text = 'Home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jurp_app_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end