classdef jurp_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        JointStatesITab              matlab.ui.container.Tab
        JURP19ManualcontrollerLabel  matlab.ui.control.Label
        HomeButton                   matlab.ui.control.Button
        ManualcontrolPanel           matlab.ui.container.Panel
        ShoulderSpinner              matlab.ui.control.Spinner
        TransverseSpinner            matlab.ui.control.Spinner
        ElbowSpinner                 matlab.ui.control.Spinner
        ShoulderradSliderLabel       matlab.ui.control.Label
        ShoulderradSlider            matlab.ui.control.Slider
        TransverseradSliderLabel     matlab.ui.control.Label
        TransverseradSlider          matlab.ui.control.Slider
        ElbowradSliderLabel          matlab.ui.control.Label
        ElbowradSlider               matlab.ui.control.Slider
        PublishButton                matlab.ui.control.StateButton
        StartButton                  matlab.ui.control.Button
        JointStatesIITab             matlab.ui.container.Tab
        StartPanel                   matlab.ui.container.Panel
        ShoulderEditField_2          matlab.ui.control.NumericEditField
        ShoulderEditField_2Label     matlab.ui.control.Label
        TransverseEditField_2        matlab.ui.control.NumericEditField
        TransverseEditField_2Label   matlab.ui.control.Label
        ElbowEditField_2             matlab.ui.control.NumericEditField
        ElbowEditField_2Label        matlab.ui.control.Label
        TossPanel                    matlab.ui.container.Panel
        ShoulderEditField_3          matlab.ui.control.NumericEditField
        ShoulderEditField_3Label     matlab.ui.control.Label
        TransverseEditField_3        matlab.ui.control.NumericEditField
        TransverseEditField_3Label   matlab.ui.control.Label
        ElbowEditField_3             matlab.ui.control.NumericEditField
        ElbowEditField_3Label        matlab.ui.control.Label
        CatchPanel                   matlab.ui.container.Panel
        ShoulderEditField_4          matlab.ui.control.NumericEditField
        ShoulderEditField_4Label     matlab.ui.control.Label
        TransverseEditField_4        matlab.ui.control.NumericEditField
        TransverseEditField_4Label   matlab.ui.control.Label
        ElbowEditField_4             matlab.ui.control.NumericEditField
        ElbowEditField_4Label        matlab.ui.control.Label
        JuggleButton                 matlab.ui.control.Button
        PositionsTab                 matlab.ui.container.Tab
        DesiredEEpositionPanel       matlab.ui.container.Panel
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        GoButton                     matlab.ui.control.StateButton
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ShoulderradSlider
        function ShoulderradSliderValueChanged(app, event)
            value = app.ShoulderradSlider.Value;
            app.ShoulderSpinner.Value = value;
        end

        % Value changed function: TransverseradSlider
        function TransverseradSliderValueChanged(app, event)
            value = app.TransverseradSlider.Value;
            app.TransverseSpinner.Value = value;
        end

        % Value changed function: ElbowradSlider
        function ElbowradSliderValueChanged(app, event)
            value = app.ElbowradSlider.Value;
            app.ElbowSpinner.Value = value;
            app.ElbowradSlider.Value = value;
        end

        % Value changed function: ShoulderSpinner
        function ShoulderSpinnerValueChanged(app, event)
            value = app.ShoulderSpinner.Value;
            app.ShoulderradSlider.Value = value;
        end

        % Value changed function: TransverseSpinner
        function TransverseSpinnerValueChanged(app, event)
            value = app.TransverseSpinner.Value;
            app.TransverseradSlider.Value=value;
        end

        % Value changed function: ElbowSpinner
        function ElbowSpinnerValueChanged(app, event)
            value = app.ElbowSpinner.Value;
            app.ElbowradSlider.Value=value;
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.ShoulderradSlider.Value = 0;
            app.ShoulderSpinner.Value = 0;
            app.TransverseSpinner.Value = 0;
            app.TransverseradSlider.Value = 0;
            app.ElbowSpinner.Value = pi/2;
            app.ElbowradSlider.Value = pi/2;
            
            JointPub = rospublisher('desired_joints','geometry_msgs/Vector3');
            JointMsg = rosmessage('geometry_msgs/Vector3');
            JointMsg.X = 0;
            JointMsg.Y = 0;
            JointMsg.Z = pi/2;
            send(JointPub,JointMsg)
                        
        end

        % Value changed function: PublishButton
        function PublishButtonValueChanged(app, event)
            JointPub = rospublisher('desired_joints','geometry_msgs/Vector3');
            JointMsg = rosmessage('geometry_msgs/Vector3');
            JointMsg.X = app.ShoulderradSlider.Value;
            JointMsg.Y = app.TransverseradSlider.Value;
            JointMsg.Z = app.ElbowradSlider.Value; 
            send(JointPub,JointMsg)        
        end

        % Callback function
        function AnalyzeTabSizeChanged(app, event)
            
        end

        % Button pushed function: JuggleButton
        function JuggleButtonPushed(app, event)
            start_value1 = app.ShoulderEditField_2.Value;
            start_value2 = app.TransverseEditField_2.Value;
            start_value3 = app.ElbowEditField_2.Value;
            
            toss_value1 = app.ShoulderEditField_3.Value;
            toss_value2 = app.TransverseEditField_3.Value;
            toss_value3 = app.ElbowEditField_3.Value;
            
            catch_value1 = app.ShoulderEditField_4.Value;
            catch_value2 = app.TransverseEditField_4.Value;
            catch_value3 = app.ElbowEditField_4.Value;
            
            app.ShoulderradSlider.Value = catch_value1;
            app.ShoulderSpinner.Value = catch_value1;
            app.TransverseSpinner.Value = catch_value2;
            app.TransverseradSlider.Value = catch_value2;
            app.ElbowSpinner.Value = catch_value3;
            app.ElbowradSlider.Value = catch_value3;            
            
            JointPub = rospublisher('desired_joints','geometry_msgs/Vector3');
            JointMsg = rosmessage('geometry_msgs/Vector3');
            
            command1 = [ start_value1 toss_value1 catch_value1 ];
            command2 = [ start_value2 toss_value2 catch_value2 ];
            command3 = [ start_value3 toss_value3 catch_value3 ];
            for i = 1:3
                JointMsg.X = command1(i);
                JointMsg.Y = command2(i);
                JointMsg.Z = command3(i);
                send(JointPub,JointMsg) 
                pause(0.5)
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.ShoulderradSlider.Value = 0;
            app.ShoulderSpinner.Value = 0;
            app.TransverseSpinner.Value = 0;
            app.TransverseradSlider.Value = 0;
            app.ElbowSpinner.Value = 0;
            app.ElbowradSlider.Value = 0;
            
            JointPub = rospublisher('desired_joints','geometry_msgs/Vector3');
            JointMsg = rosmessage('geometry_msgs/Vector3');
            JointMsg.X = 0;
            JointMsg.Y = 0;
            JointMsg.Z = 0;
            send(JointPub,JointMsg)
        end

        % Value changed function: GoButton
        function GoButtonValueChanged(app, event)
            value = app.GoButton.Value;
            PosPub = rospublisher('desired_position','geometry_msgs/Vector3');
            PosMsg = rosmessage('geometry_msgs/Vector3');
            PosMsg.X = app.XEditField.Value;
            PosMsg.Y = app.YEditField.Value;
            PosMsg.Z = app.ZEditField.Value;
            send(PosPub,PosMsg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JointStatesITab = uitab(app.TabGroup);
            app.JointStatesITab.Title = 'Joint States (I)';
            app.JURP19ManualcontrollerLabel = uilabel(app.JointStatesITab);
            app.JURP19ManualcontrollerLabel.Text = 'JURP19: Manual controller';
            app.HomeButton = uibutton(app.JointStatesITab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ManualcontrolPanel = uipanel(app.JointStatesITab);
            app.ManualcontrolPanel.Title = 'Manual control';
            app.ShoulderSpinner = uispinner(app.ManualcontrolPanel);
            app.ShoulderSpinner.Step = 0.1;
            app.ShoulderSpinner.Limits = [-1.5708 1.5708];
            app.ShoulderSpinner.ValueChangedFcn = createCallbackFcn(app, @ShoulderSpinnerValueChanged, true);
            app.TransverseSpinner = uispinner(app.ManualcontrolPanel);
            app.TransverseSpinner.Step = 0.1;
            app.TransverseSpinner.Limits = [-3.1416 3.1416];
            app.TransverseSpinner.ValueChangedFcn = createCallbackFcn(app, @TransverseSpinnerValueChanged, true);
            app.ElbowSpinner = uispinner(app.ManualcontrolPanel);
            app.ElbowSpinner.Step = 0.1;
            app.ElbowSpinner.Limits = [0 4.71238898038469];
            app.ElbowSpinner.ValueChangedFcn = createCallbackFcn(app, @ElbowSpinnerValueChanged, true);
            app.ShoulderradSliderLabel = uilabel(app.ManualcontrolPanel);
            app.ShoulderradSliderLabel.Text = 'Shoulder (rad)';
            app.ShoulderradSlider = uislider(app.ManualcontrolPanel);
            app.ShoulderradSlider.Limits = [-1.5707963267949 1.5707963267949];
            app.ShoulderradSlider.MajorTicks = [-1.5707963267949 1.5707963267949];
            app.ShoulderradSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderradSliderValueChanged, true);
            app.ShoulderradSlider.MinorTicks = 0;
            app.TransverseradSliderLabel = uilabel(app.ManualcontrolPanel);
            app.TransverseradSliderLabel.Text = 'Transverse (rad)';
            app.TransverseradSlider = uislider(app.ManualcontrolPanel);
            app.TransverseradSlider.Limits = [-0.8 0.8];
            app.TransverseradSlider.MajorTicks = [-0.8 0.8];
            app.TransverseradSlider.ValueChangedFcn = createCallbackFcn(app, @TransverseradSliderValueChanged, true);
            app.TransverseradSlider.MinorTicks = 0;
            app.ElbowradSliderLabel = uilabel(app.ManualcontrolPanel);
            app.ElbowradSliderLabel.Text = 'Elbow (rad)';
            app.ElbowradSlider = uislider(app.ManualcontrolPanel);
            app.ElbowradSlider.Limits = [0 4.71238898038469];
            app.ElbowradSlider.MajorTicks = [0 1.5707963267949 4.71238898038469];
            app.ElbowradSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowradSliderValueChanged, true);
            app.ElbowradSlider.MinorTicks = 0;
            app.PublishButton = uibutton(app.ManualcontrolPanel, 'state');
            app.PublishButton.ValueChangedFcn = createCallbackFcn(app, @PublishButtonValueChanged, true);
            app.PublishButton.Text = 'Publish';
            app.StartButton = uibutton(app.JointStatesITab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.JointStatesIITab = uitab(app.TabGroup);
            app.JointStatesIITab.Title = 'Joint States (II)';
            app.StartPanel = uipanel(app.JointStatesIITab);
            app.StartPanel.Title = 'Start';
            app.ShoulderEditField_2 = uieditfield(app.StartPanel, 'numeric');
            app.ShoulderEditField_2.Limits = [-1.5708 1.5708];
            app.ShoulderEditField_2Label = uilabel(app.StartPanel);
            app.ShoulderEditField_2Label.Text = 'Shoulder';
            app.TransverseEditField_2 = uieditfield(app.StartPanel, 'numeric');
            app.TransverseEditField_2.Limits = [-0.8 0.8];
            app.TransverseEditField_2Label = uilabel(app.StartPanel);
            app.TransverseEditField_2Label.Text = 'Transverse';
            app.ElbowEditField_2 = uieditfield(app.StartPanel, 'numeric');
            app.ElbowEditField_2.Limits = [0 4.7124];
            app.ElbowEditField_2.Value = 1;
            app.ElbowEditField_2Label = uilabel(app.StartPanel);
            app.ElbowEditField_2Label.Text = 'Elbow';
            app.TossPanel = uipanel(app.JointStatesIITab);
            app.TossPanel.Title = 'Toss';
            app.ShoulderEditField_3 = uieditfield(app.TossPanel, 'numeric');
            app.ShoulderEditField_3.Limits = [-1.5708 1.5708];
            app.ShoulderEditField_3.Value = 0.7;
            app.ShoulderEditField_3Label = uilabel(app.TossPanel);
            app.ShoulderEditField_3Label.Text = 'Shoulder';
            app.TransverseEditField_3 = uieditfield(app.TossPanel, 'numeric');
            app.TransverseEditField_3.Limits = [-0.8 0.8];
            app.TransverseEditField_3Label = uilabel(app.TossPanel);
            app.TransverseEditField_3Label.Text = 'Transverse';
            app.ElbowEditField_3 = uieditfield(app.TossPanel, 'numeric');
            app.ElbowEditField_3.Limits = [0 4.7124];
            app.ElbowEditField_3.Value = 2;
            app.ElbowEditField_3Label = uilabel(app.TossPanel);
            app.ElbowEditField_3Label.Text = 'Elbow';
            app.CatchPanel = uipanel(app.JointStatesIITab);
            app.CatchPanel.Title = 'Catch';
            app.ShoulderEditField_4 = uieditfield(app.CatchPanel, 'numeric');
            app.ShoulderEditField_4.Limits = [-1.5708 1.5708];
            app.ShoulderEditField_4Label = uilabel(app.CatchPanel);
            app.ShoulderEditField_4Label.Text = 'Shoulder';
            app.TransverseEditField_4 = uieditfield(app.CatchPanel, 'numeric');
            app.TransverseEditField_4.Limits = [-0.8 0.8];
            app.TransverseEditField_4Label = uilabel(app.CatchPanel);
            app.TransverseEditField_4Label.Text = 'Transverse';
            app.ElbowEditField_4 = uieditfield(app.CatchPanel, 'numeric');
            app.ElbowEditField_4.Limits = [0 4.7124];
            app.ElbowEditField_4.Value = 1;
            app.ElbowEditField_4Label = uilabel(app.CatchPanel);
            app.ElbowEditField_4Label.Text = 'Elbow';
            app.JuggleButton = uibutton(app.JointStatesIITab, 'push');
            app.JuggleButton.ButtonPushedFcn = createCallbackFcn(app, @JuggleButtonPushed, true);
            app.JuggleButton.Text = 'Juggle';
            app.PositionsTab = uitab(app.TabGroup);
            app.PositionsTab.Title = 'Positions';
            app.DesiredEEpositionPanel = uipanel(app.PositionsTab);
            app.DesiredEEpositionPanel.Title = 'Desired EE position';
            app.XEditField = uieditfield(app.DesiredEEpositionPanel, 'numeric');
            app.XEditField.Limits = [-1.5708 1.5708];
            app.XEditField.Value = 0.2;
            app.XEditFieldLabel = uilabel(app.DesiredEEpositionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.YEditField = uieditfield(app.DesiredEEpositionPanel, 'numeric');
            app.YEditField.Limits = [-0.8 0.8];
            app.YEditField.Value = 0.3;
            app.YEditFieldLabel = uilabel(app.DesiredEEpositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.ZEditField = uieditfield(app.DesiredEEpositionPanel, 'numeric');
            app.ZEditField.Limits = [0 4.7124];
            app.ZEditField.Value = 1;
            app.ZEditFieldLabel = uilabel(app.DesiredEEpositionPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.GoButton = uibutton(app.DesiredEEpositionPanel, 'state');
            app.GoButton.ValueChangedFcn = createCallbackFcn(app, @GoButtonValueChanged, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jurp_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end